classdef assginment2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        invertButton        matlab.ui.control.Button
        binarizationButton  matlab.ui.control.Button
        loadButton          matlab.ui.control.Button
        UIAxes_3            matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Origin_img % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            filename="C:\知能機械設計\logo_csse.png"
            app.Origin_img=imread(filename)
            imshow(app.Origin_img,'Parent',app.UIAxes)
        end

        % Button pushed function: binarizationButton
        function binarizationButtonPushed(app, event)
            Gray_img=rgb2gray(app.Origin_img)
            imshow(Gray_img,'Parent',app.UIAxes_2)
        end

        % Button pushed function: invertButton
        function invertButtonPushed(app, event)
            Gray_img = rgb2gray(app.Origin_img); % まずグレースケールに変換
            Gray_img = im2double(Gray_img); % double型に変換
            Inverted_img = 1 - Gray_img; % 白黒反転
            imshow(Inverted_img, 'Parent', app.UIAxes_3);
        end

        % Callback function: not associated with a component
        function enhanceRedButtonPushed(app, event)
            Enhanced_img = app.Origin_img; % 元の画像をコピー
            Enhanced_img(:,:,1) = Enhanced_img(:,:,1) * 1.5; % 赤チャンネルを強調
            Enhanced_img(Enhanced_img > 255) = 255; % ピクセル値を255でクリップ
            imshow(Enhanced_img, 'Parent', app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'GrayImage')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'InvertImage')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ZTick = [];
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.binarizationButton = uibutton(app.UIFigure, 'push');
            app.binarizationButton.ButtonPushedFcn = createCallbackFcn(app, @binarizationButtonPushed, true);
            app.binarizationButton.Text = 'binarization';
            app.invertButton = uibutton(app.UIFigure, 'push');
            app.invertButton.ButtonPushedFcn = createCallbackFcn(app, @invertButtonPushed, true);
            app.invertButton.Text = 'invert';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assginment2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end